classdef startSeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SelectFileButton          matlab.ui.control.Button
        SelectSaveFileButton      matlab.ui.control.Button
        IdentifyCellContainingRegionsCheckBox  matlab.ui.control.CheckBox
        CONFIRMButton             matlab.ui.control.Button
        LightChannelSpinnerLabel  matlab.ui.control.Label
        LightChannelSpinner       matlab.ui.control.Spinner
        FLUOChannelSpinnerLabel   matlab.ui.control.Label
        FLUOChannelSpinner        matlab.ui.control.Spinner
        DisplayChannelsButton     matlab.ui.control.Button
        LightimageisBrightfieldCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        openfile % File to Open
        savefilename % File to save to
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectSaveFileButton.Enable = 0;
            app.DisplayChannelsButton.Enable = 0;
            app.CONFIRMButton.Enable = 0;
            app.LightChannelSpinner.Enable = 0;
            app.FLUOChannelSpinner.Enable = 0;
            app.IdentifyCellContainingRegionsCheckBox.Enable = 0;
            app.LightimageisBrightfieldCheckBox.Enable = 0;
            
            app.LightChannelSpinner.Value=1;
            app.FLUOChannelSpinner.Value=2;
            app.IdentifyCellContainingRegionsCheckBox.Value = 1;
        end

        % Button pushed function: SelectFileButton
        function loadFile(app, event)
            [filename, path] = uigetfile('*.mldatx;*.nd2', 'Select file to load.');
            
            % If no file was loaded do nothing
            if filename == 0
                return
            end
            
            % File to open
            app.openfile = fullfile(path, filename);
            [~, ~, ext] = fileparts(app.openfile);
            
            switch ext
                case '.mldatx'
                    app.SelectSaveFileButton.Enable = 0;
                    confirm(app)
                    
                case '.nd2'
                    app.SelectSaveFileButton.Enable = 1;
                    app.DisplayChannelsButton.Enable = 1;
                    app.LightChannelSpinner.Enable = 1;
                    app.FLUOChannelSpinner.Enable = 1;
                    app.IdentifyCellContainingRegionsCheckBox.Enable = 1;
                    app.LightimageisBrightfieldCheckBox.Enable = 1;
            end
        end

        % Button pushed function: SelectSaveFileButton
        function selectSaveFile(app, event)
            app.savefilename = uiputfile("", "Select File for Saving Results");
            % If no file was loaded do nothing
            if app.savefilename == 0
                return
            end
            
            app.CONFIRMButton.Enable = 1;
        end

        % Button pushed function: DisplayChannelsButton
        function displayChannels(app, event)
            bfInitLogging('OFF');
            reader = loci.formats.Memoizer(bfGetReader(), 0);
            reader.setId(app.openfile);
            
            for c=1:reader.getSizeC                
                ix = reader.getIndex(0, c-1, reader.getSizeT - 1) + 1;
                img = bfGetPlane(reader, ix);
                img8bit = uint8(rescale(img, 0, 255));
                imgWText = insertText(img8bit, [5, 5], c, 'FontSize',100, 'TextColor', 'r');
                allframes{c} = [imgWText];
            end
                        
            figure;
            montage(allframes)
        end

        % Button pushed function: CONFIRMButton
        function confirm(app, event)
            doSeg(app.openfile, app.savefilename, app.IdentifyCellContainingRegionsCheckBox.Value, ...
                app.LightChannelSpinner.Value, app.FLUOChannelSpinner.Value, app.LightimageisBrightfieldCheckBox.Value)
            UIFigureCloseRequest(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadFile, true);
            app.SelectFileButton.Text = 'Select File';
            app.SelectSaveFileButton = uibutton(app.UIFigure, 'push');
            app.SelectSaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectSaveFile, true);
            app.SelectSaveFileButton.Text = 'Select Save File';
            app.IdentifyCellContainingRegionsCheckBox = uicheckbox(app.UIFigure);
            app.IdentifyCellContainingRegionsCheckBox.Text = 'Identify Cell Containing Regions';
            app.CONFIRMButton = uibutton(app.UIFigure, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @confirm, true);
            app.CONFIRMButton.Text = 'CONFIRM';
            app.LightChannelSpinnerLabel = uilabel(app.UIFigure);
            app.LightChannelSpinnerLabel.Text = 'Light Channel';
            app.LightChannelSpinner = uispinner(app.UIFigure);
            app.FLUOChannelSpinnerLabel = uilabel(app.UIFigure);
            app.FLUOChannelSpinnerLabel.Text = 'FLUO Channel';
            app.FLUOChannelSpinner = uispinner(app.UIFigure);
            app.DisplayChannelsButton = uibutton(app.UIFigure, 'push');
            app.DisplayChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @displayChannels, true);
            app.DisplayChannelsButton.Text = 'Display Channels';
            app.LightimageisBrightfieldCheckBox = uicheckbox(app.UIFigure);
            app.LightimageisBrightfieldCheckBox.Text = 'Light image is Brightfield';
        end
    end

    methods (Access = public)

        % Construct app
        function app = startSeg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end